classdef dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FilesMenu                  matlab.ui.container.Menu
        OpenxmlfileMenu            matlab.ui.container.Menu
        ExitMenu                   matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        StatusEditField            matlab.ui.control.EditField
        StatusEditFieldLabel       matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout8                matlab.ui.container.GridLayout
        StartButton                matlab.ui.control.StateButton
        ResetButton                matlab.ui.control.Button
        GridLayout9                matlab.ui.container.GridLayout
        MDropDown                  matlab.ui.control.DropDown
        MDropDownLabel             matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        TauSpinner                 matlab.ui.control.Spinner
        TauSpinnerLabel            matlab.ui.control.Label
        LatlSpinner                matlab.ui.control.Spinner
        LatlSpinnerLabel           matlab.ui.control.Label
        LatPSpinner                matlab.ui.control.Spinner
        LatPSpinnerLabel           matlab.ui.control.Label
        LatASpinner                matlab.ui.control.Spinner
        LatASpinnerLabel           matlab.ui.control.Label
        DorlSpinner                matlab.ui.control.Spinner
        DorlSpinnerLabel           matlab.ui.control.Label
        DorPSpinner                matlab.ui.control.Spinner
        DorPSpinnerLabel           matlab.ui.control.Label
        DorASpinner                matlab.ui.control.Spinner
        DorASpinnerLabel           matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
        GridLayout4                matlab.ui.container.GridLayout
        PlottingLinkDropDown       matlab.ui.control.DropDown
        PlottingLinkDropDownLabel  matlab.ui.control.Label
        PlottingDataDropDown       matlab.ui.control.DropDown
        PlottingDataDropDownLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        ISSTARTED = false; % Get simulation is started
        MOTIONMATRIX = -1; % Gait Motion Matrices
        % -1 -> Not selected
        % 0 -> Vertical
        % 1 -> Serpentine
        % 2 -> Sidewinding
        GAITVECTOR = [0 0 0 0 0 0 0]; % Gait vector dA dP dl lA lP ll tau

        XMLFILENAME % XML file name
        XMLPATH % XML file path

        STATUS = ""; % Status message

    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pyExec = 'C:\Users\doore\anaconda3\envs\rllib\python.exe';
            pyRoot = fileparts(pyExec);
            p = getenv('PATH');
            p = strsplit(p, ';');
            addToPath = {
                pyRoot
                fullfile(pyRoot, 'Library', 'mingw-w64', 'bin')
                fullfile(pyRoot, 'Library', 'usr', 'bin')
                fullfile(pyRoot, 'Library', 'bin')
                fullfile(pyRoot, 'Scripts')
                fullfile(pyRoot, 'bin')
                };
            p = [addToPath(:); p(:)];
            p = unique(p, 'stable');
            p = strjoin(p, ';');
            setenv('PATH', p);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            app.delete
        end

        % Menu selected function: OpenxmlfileMenu
        function OpenxmlfileMenuSelected(app, event)
            [file, path] = uigetfile('*.xml');
            app.XMLFILENAME = file;
            app.XMLPATH = path;
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            
            if value
                app.STATUS = app.STATUS + string(value);
                text = pyrunfile('interfaces.py');

                app.StatusEditField.Value = text;
            else
                app.STATUS = "";
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files';
            app.OpenxmlfileMenu = uimenu(app.FilesMenu);
            app.OpenxmlfileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenxmlfileMenuSelected, true);
            app.OpenxmlfileMenu.Text = 'Open xml file';
            app.ExitMenu = uimenu(app.FilesMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout3);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.PlottingDataDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingDataDropDownLabel.Text = 'Plotting Data';
            app.PlottingDataDropDown = uidropdown(app.GridLayout4);
            app.PlottingDataDropDown.Items = {'Velocity', 'Orientation', 'Force (Torque)'};
            app.PlottingDataDropDown.Value = 'Velocity';
            app.PlottingLinkDropDownLabel = uilabel(app.GridLayout4);
            app.PlottingLinkDropDownLabel.Text = 'Plotting Link';
            app.PlottingLinkDropDown = uidropdown(app.GridLayout4);
            app.PlottingLinkDropDown.Items = {'All', 'CoM', 'Link1', 'Link2', 'Link3', 'Link4', 'Link5', 'Link6', 'Link7', 'Link8', 'Link9', 'Link10', 'Link11', 'Link12', 'Link13', 'Link14', 'Link15'};
            app.PlottingLinkDropDown.Value = 'All';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.DorASpinnerLabel = uilabel(app.GridLayout6);
            app.DorASpinnerLabel.Text = 'Dor. A';
            app.DorASpinner = uispinner(app.GridLayout6);
            app.DorPSpinnerLabel = uilabel(app.GridLayout6);
            app.DorPSpinnerLabel.Text = 'Dor. P';
            app.DorPSpinner = uispinner(app.GridLayout6);
            app.DorlSpinnerLabel = uilabel(app.GridLayout6);
            app.DorlSpinnerLabel.Text = 'Dor. l';
            app.DorlSpinner = uispinner(app.GridLayout6);
            app.LatASpinnerLabel = uilabel(app.GridLayout6);
            app.LatASpinnerLabel.Text = 'Lat. A';
            app.LatASpinner = uispinner(app.GridLayout6);
            app.LatPSpinnerLabel = uilabel(app.GridLayout6);
            app.LatPSpinnerLabel.Text = 'Lat. P';
            app.LatPSpinner = uispinner(app.GridLayout6);
            app.LatlSpinnerLabel = uilabel(app.GridLayout6);
            app.LatlSpinnerLabel.Text = 'Lat. l';
            app.LatlSpinner = uispinner(app.GridLayout6);
            app.TauSpinnerLabel = uilabel(app.GridLayout6);
            app.TauSpinnerLabel.Text = 'Tau';
            app.TauSpinner = uispinner(app.GridLayout6);
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.MDropDownLabel = uilabel(app.GridLayout9);
            app.MDropDownLabel.Text = 'M';
            app.MDropDown = uidropdown(app.GridLayout9);
            app.MDropDown.Items = {'Vertical', 'Serpentine', 'Sidewinding'};
            app.MDropDown.Value = 'Vertical';
            app.ResetButton = uibutton(app.GridLayout8, 'push');
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.GridLayout8, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StatusEditFieldLabel = uilabel(app.GridLayout);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.GridLayout, 'text');
            app.StatusEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end